classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Slider                 matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
        FunctionDropDown       matlab.ui.control.DropDown
        FunctionDropDownLabel  matlab.ui.control.Label
        LoadButton             matlab.ui.control.Button
        Image_D                matlab.ui.control.Image
        Image_R                matlab.ui.control.Image
        Image_L                matlab.ui.control.Image
    end


    properties (Access = private)
        im_L
        im_R
        im_D
    end

    methods (Access = private)

        function showComponent(app, isShow)
            app.FunctionDropDown.Visible = isShow;
            app.FunctionDropDownLabel.Visible = isShow;
            app.Image_D.Visible = isShow;
            app.Slider.Visible = isShow;
            app.SliderLabel.Visible = isShow;
        end

        function show(app)
            val = app.FunctionDropDown.Value;
            showComponent(app, true);
            switch val
                case '1'
                    app.Slider.Visible = false;
                    app.SliderLabel.Visible = false;
                    app.Image_D.ImageSource = cat(3,app.im_D,app.im_D,app.im_D);
                case '2'
                    val = app.Slider.Value;
                    if(val <= 0.5)
                        w = app.im_D - val;
                    elseif(val > 0.5)
                        w = val - app.im_D;
                    end

                    h1 = ones(11) / 121;
                    im_blur = imfilter(app.im_L, h1);
                    im_blur = im2double(im_blur);
                    im_blur = (im_blur - min(im_blur(:))) / (max(im_blur(:)) - min(im_blur(:)));

                    h2 = [-0.05 -0.05 -0.05; -0.05 1.4 -0.05; -0.05 -0.05 -0.05];
                    im_sharp = imfilter(app.im_L, h2);
                    im_sharp = im2double(im_sharp);
                    im_sharp = (im_sharp - min(im_sharp(:))) / (max(im_sharp(:)) - min(im_sharp(:)));

                    im_refocus = w.*im_sharp + (1-w).*im_blur;
                    app.Image_D.ImageSource = im_refocus;
                case '3'
                    val = app.Slider.Value;
                    if(val <= 0.5)
                        w = app.im_D - val;
                    elseif(val > 0.5)
                        w = val - app.im_D;
                    end

                    hsv = rgb2hsv(app.im_R);
                    [h,s,v] = imsplit(hsv);
                    s_new = s.*w;
                    hsv_new = cat(3,h,s_new,v);
                    rgb_new = hsv2rgb(hsv_new);
                    app.Image_D.ImageSource = rgb_new;
                otherwise
                    disp('Error: No this option')
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            showComponent(app, false);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            clc
            [file,path] = uigetfile('*.png');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                disp(['User selected: ',fullfile(path,file)]);
                if strfind(file, 'left')~=-1
                    app.im_L = imread([path file]);
                    app.Image_L.ImageSource = app.im_L;

                    another_file = replace(file,'left','right');
                    app.im_R = imread([path another_file]);
                    app.Image_R.ImageSource = app.im_R;

                    disp(['Load the left image: ',fullfile(path,file)]);
                    disp(['Load the right image: ',fullfile(path,another_file)]);
                elseif strfind(file, 'right')~=-1
                    app.im_R = imread([path file]);
                    app.Image_R.ImageSource = app.im_R;

                    another_file = replace(file,'right','left');
                    app.im_L = imread([path another_file]);
                    app.Image_L.ImageSource = app.im_L;

                    disp(['Load the left image: ',fullfile(path,another_file)]);
                    disp(['Load the right image: ',fullfile(path,file)]);
                else
                    disp('Error: Cannot load the pair of images');
                end
            end
            prompt = {'Enter the windowsize:','Enter the disparity:','Enter the smoothing:'};
            dlgtitle = 'Stereomatch paramaters';
            dims = [1 60];
            definput = {'31','50','0'};
            param = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(param),return,end
            
            windowsize = str2double(param{1});
            disparity = str2double(param{2});
            smoothing = str2double(param{3});
            if isnan(windowsize) || windowsize<0
                windowsize = str2double(definput{1});
                message = sprintf('Error! The windowsize is set to default as %.2f',windowsize);
                uiwait(warndlg(message));
            end
            if isnan(disparity) || disparity<0
                disparity = str2double(definput{2});
                message = sprintf('Error! The disparity is set to default as %.2f',disparity);
                uiwait(warndlg(message));
            end
            if isnan(smoothing)
                smoothing = str2double(definput{3});
                message = sprintf('Error! The smoothing is set to default as %.2f',smoothing);
                uiwait(warndlg(message));
            end
            
            depthmap = stereomatch_s(app.im_L, app.im_R, windowsize, disparity, smoothing);
            app.im_D = (depthmap - min(depthmap(:))) / (max(depthmap(:)) - min(depthmap(:)));
            imwrite(app.im_D,'depthmap.png');
            show(app);
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            show(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            show(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image_L = uiimage(app.UIFigure);
            app.Image_R = uiimage(app.UIFigure);
            app.Image_D = uiimage(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = {'Function'; ''};
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'Depthmap', 'Refocus', 'Colorization', ''};
            app.FunctionDropDown.ItemsData = {'1', '2', '3'};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = '1';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end